classdef VR_App_Params < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VRExperimentsParamsUIFigure   matlab.ui.Figure
        AmplitudeKnobLabel            matlab.ui.control.Label
        AmplitudeKnob                 matlab.ui.control.DiscreteKnob
        VelocityKnobLabel             matlab.ui.control.Label
        VelocityKnob                  matlab.ui.control.DiscreteKnob
        GUIIRFSwitchLabel             matlab.ui.control.Label
        GUIIRFSwitch                  matlab.ui.control.Switch
        TrialNumberDropDownLabel      matlab.ui.control.Label
        TrialNumberDropDown           matlab.ui.control.DropDown
        DataDetrendSwitchLabel        matlab.ui.control.Label
        DataDetrendSwitch             matlab.ui.control.Switch
        VelocityasInputSwitchLabel    matlab.ui.control.Label
        VelocityasInputSwitch         matlab.ui.control.Switch
        ContinueButton                matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        VelocityasOutputSwitchLabel   matlab.ui.control.Label
        VelocityasOutputSwitch        matlab.ui.control.Switch
        VRExperimentsParametersLabel  matlab.ui.control.Label
        OptionsLabel                  matlab.ui.control.Label
        Lamp_1                        matlab.ui.control.Lamp
        Lamp_2                        matlab.ui.control.Lamp
        Lamp_3                        matlab.ui.control.Lamp
        Lamp_4                        matlab.ui.control.Lamp
        Label_1                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
    end

    
    properties (Access = public)
        Subject_data;
        SubjIndex;
        
        bDDT_VR;
        bDDT_Output;
        bDetrend;
        bGui_IRF;
        TrialNumber;
        Amplitude;
        Velocity;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Subject_data, SubjIndex)
            app.Subject_data = Subject_data;
            app.SubjIndex = SubjIndex;
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete
            close all
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.Amplitude = str2num(app.AmplitudeKnob.Value);
            app.Velocity = str2num(app.VelocityKnob.Value);
            app.TrialNumber = str2num(app.TrialNumberDropDown.Value);
            app.bDDT_VR = str2num(app.VelocityasInputSwitch.Value);
            app.bDDT_Output = str2num(app.VelocityasOutputSwitch.Value);
            app.bGui_IRF = str2num(app.GUIIRFSwitch.Value);
            app.bDetrend = str2num(app.DataDetrendSwitch.Value);
            VR_Analysis_Versatile_App(app.SubjIndex, app.Subject_data, app.Amplitude, app.Velocity, app.TrialNumber, app.bDetrend, app.bDDT_VR, app.bDDT_Output, app.bGui_IRF)
        end

        % Value changed function: GUIIRFSwitch
        function GUIIRFSwitchValueChanged(app, event)
            value = app.GUIIRFSwitch.Value;
            if (value=='1')
                app.Lamp_1.Color = 'g';
            else
                app.Lamp_1.Color = 'r';
            end
        end

        % Value changed function: DataDetrendSwitch
        function DataDetrendSwitchValueChanged(app, event)
            value = app.DataDetrendSwitch.Value;
            if (value=='1')
                app.Lamp_2.Color = 'g';
            else
                app.Lamp_2.Color = 'r';
            end
        end

        % Value changed function: VelocityasInputSwitch
        function VelocityasInputSwitchValueChanged(app, event)
            value = app.VelocityasInputSwitch.Value;
            if (value=='1')
                app.Lamp_3.Color = 'g';
            else
                app.Lamp_3.Color = 'r';
            end
        end

        % Value changed function: VelocityasOutputSwitch
        function VelocityasOutputSwitchValueChanged(app, event)
            value = app.VelocityasOutputSwitch.Value;
            if (value=='1')
                app.Lamp_4.Color = 'g';
            else
                app.Lamp_4.Color = 'r';
            end
        end

        % Value changed function: AmplitudeKnob
        function AmplitudeKnobValueChanged(app, event)
            value = app.AmplitudeKnob.Value;
            app.Label_1.Text = value;
            
        end

        % Value changed function: VelocityKnob
        function VelocityKnobValueChanged(app, event)
            value = app.VelocityKnob.Value;
            app.Label_2.Text = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VRExperimentsParamsUIFigure = uifigure;
            app.VRExperimentsParamsUIFigure.Name = 'VR Experiments Params';
            app.AmplitudeKnobLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.AmplitudeKnobLabel.Text = 'Amplitude';
            app.AmplitudeKnob = uiknob(app.VRExperimentsParamsUIFigure, 'discrete');
            app.AmplitudeKnob.Items = {'1 deg', '2 deg', '5 deg', '10 deg'};
            app.AmplitudeKnob.ItemsData = {'1', '2', '5', '10'};
            app.AmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @AmplitudeKnobValueChanged, true);
            app.AmplitudeKnob.Value = '2';
            app.VelocityKnobLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.VelocityKnobLabel.Text = 'Velocity';
            app.VelocityKnob = uiknob(app.VRExperimentsParamsUIFigure, 'discrete');
            app.VelocityKnob.Items = {'2 dps', '5 dps', '10 dps'};
            app.VelocityKnob.ItemsData = {'2', ' 5', ' 10'};
            app.VelocityKnob.ValueChangedFcn = createCallbackFcn(app, @VelocityKnobValueChanged, true);
            app.VelocityKnob.Value = ' 5';
            app.GUIIRFSwitchLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.GUIIRFSwitchLabel.Text = 'GUI IRF';
            app.GUIIRFSwitch = uiswitch(app.VRExperimentsParamsUIFigure, 'slider');
            app.GUIIRFSwitch.ItemsData = {'0', '1'};
            app.GUIIRFSwitch.ValueChangedFcn = createCallbackFcn(app, @GUIIRFSwitchValueChanged, true);
            app.GUIIRFSwitch.Value = '0';
            app.TrialNumberDropDownLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.TrialNumberDropDownLabel.Text = 'Trial Number';
            app.TrialNumberDropDown = uidropdown(app.VRExperimentsParamsUIFigure);
            app.TrialNumberDropDown.Items = {'Trial 1', 'Trial 2', 'Trial 3'};
            app.TrialNumberDropDown.ItemsData = {'1', '2', '3'};
            app.TrialNumberDropDown.Value = '1';
            app.DataDetrendSwitchLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.DataDetrendSwitchLabel.Text = 'Data Detrend';
            app.DataDetrendSwitch = uiswitch(app.VRExperimentsParamsUIFigure, 'slider');
            app.DataDetrendSwitch.ItemsData = {'0', '1'};
            app.DataDetrendSwitch.ValueChangedFcn = createCallbackFcn(app, @DataDetrendSwitchValueChanged, true);
            app.DataDetrendSwitch.Value = '0';
            app.VelocityasInputSwitchLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.VelocityasInputSwitchLabel.Text = 'Velocity as Input';
            app.VelocityasInputSwitch = uiswitch(app.VRExperimentsParamsUIFigure, 'slider');
            app.VelocityasInputSwitch.ItemsData = {'0', '1'};
            app.VelocityasInputSwitch.ValueChangedFcn = createCallbackFcn(app, @VelocityasInputSwitchValueChanged, true);
            app.VelocityasInputSwitch.Value = '0';
            app.ContinueButton = uibutton(app.VRExperimentsParamsUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.ExitButton = uibutton(app.VRExperimentsParamsUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.VelocityasOutputSwitchLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.VelocityasOutputSwitchLabel.Text = 'Velocity as Output';
            app.VelocityasOutputSwitch = uiswitch(app.VRExperimentsParamsUIFigure, 'slider');
            app.VelocityasOutputSwitch.ItemsData = {'0', '1'};
            app.VelocityasOutputSwitch.ValueChangedFcn = createCallbackFcn(app, @VelocityasOutputSwitchValueChanged, true);
            app.VelocityasOutputSwitch.Value = '0';
            app.VRExperimentsParametersLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.VRExperimentsParametersLabel.Text = 'VR Experiments Parameters';
            app.OptionsLabel = uilabel(app.VRExperimentsParamsUIFigure);
            app.OptionsLabel.Text = 'Options:';
            app.Lamp_1 = uilamp(app.VRExperimentsParamsUIFigure);
            app.Lamp_2 = uilamp(app.VRExperimentsParamsUIFigure);
            app.Lamp_3 = uilamp(app.VRExperimentsParamsUIFigure);
            app.Lamp_4 = uilamp(app.VRExperimentsParamsUIFigure);
            app.Label_1 = uilabel(app.VRExperimentsParamsUIFigure);
            app.Label_1.Text = '';
            app.Label_2 = uilabel(app.VRExperimentsParamsUIFigure);
            app.Label_2.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = VR_App_Params(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VRExperimentsParamsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VRExperimentsParamsUIFigure)
        end
    end
end